classdef CellDensityApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GraphOptionsPanel             matlab.ui.container.Panel
        TitleSizeSpinner              matlab.ui.control.Spinner
        TitleSizeSpinnerLabel         matlab.ui.control.Label
        AxesLabelSizeSpinner          matlab.ui.control.Spinner
        AxesLabelSizeSpinnerLabel     matlab.ui.control.Label
        AxisMarkerSizeSpinner         matlab.ui.control.Spinner
        AxisMarkerSizeSpinnerLabel    matlab.ui.control.Label
        FillPointsCheckBox            matlab.ui.control.CheckBox
        PointTransparencySlider       matlab.ui.control.Slider
        PointTransparencySliderLabel  matlab.ui.control.Label
        PointColorDropDown            matlab.ui.control.DropDown
        PointColorDropDownLabel       matlab.ui.control.Label
        PointSizeSlider               matlab.ui.control.Slider
        PointSizeSliderLabel          matlab.ui.control.Label
        YAxisTypeDropDown             matlab.ui.control.DropDown
        YAxisTypeDropDownLabel        matlab.ui.control.Label
        XAxisTypeDropDown             matlab.ui.control.DropDown
        XAxisTypeDropDownLabel        matlab.ui.control.Label
        SaveFigureButton              matlab.ui.control.Button
        ClearAllButton                matlab.ui.control.Button
        GatingSelectionsPanel         matlab.ui.container.Panel
        ClearButton                   matlab.ui.control.Button
        AddParameterButton            matlab.ui.control.Button
        SubmitGroupingButton          matlab.ui.control.Button
        CurrentParamsTextArea         matlab.ui.control.TextArea
        CurrentParamsTextAreaLabel    matlab.ui.control.Label
        SubmitGatingButton            matlab.ui.control.Button
        OptionsDropDown               matlab.ui.control.DropDown
        OptionsDropDownLabel          matlab.ui.control.Label
        VariableDropDown              matlab.ui.control.DropDown
        VariableDropDownLabel         matlab.ui.control.Label
        LoadDataButton                matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        PlotTab                       matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        HistogramTab                  matlab.ui.container.Tab
        UIAxes3                       matlab.ui.control.UIAxes
        AxisSelectionsPanel           matlab.ui.container.Panel
        YAxisDropDown                 matlab.ui.control.DropDown
        YAxisDropDownLabel            matlab.ui.control.Label
        XAxisDropDown                 matlab.ui.control.DropDown
        XAxisDropDownLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        Data % table that holds all quantitative data
        metaData % table that holds all non-numerical data
        x_data % contains the column selected for x-axis
        y_data % contains the column selected for y-axis
        row_index % property that keeps track of which row index will be plotted
        temp_row_index % temperary index held to keep track of parameters before gating/grouping submissin
        metaCols % column names of the metadata
        holding % checks if plot is holding
        
        currentPlots % contains the labels of all displayed plots for legend purposes
        
        
    end
    
    methods (Access = private)
        
        % plots data based on most recent dropdown values
        function refreshPlot(app)
            
            app.row_index = app.temp_row_index;
            
            % ensures columns to be plotted are up-to-date with dropdown selections 
            app.x_data = app.Data.(app.XAxisDropDown.Value);
            app.y_data = app.Data.(app.YAxisDropDown.Value);
            
            
            % linear scale
            app.UIAxes.Title.String = app.XAxisDropDown.Value + " vs. " + app.YAxisDropDown.Value;
            app.UIAxes.XLabel.String = app.XAxisDropDown.Value;
            app.UIAxes.YLabel.String = app.YAxisDropDown.Value;
            
            app.UIAxes.FontSize = app.AxisMarkerSizeSpinner.Value; % changes font size of labels. THIS NEEDS TO BE TUNED STILL!!
                       
            app.UIAxes.LabelFontSizeMultiplier = app.AxesLabelSizeSpinner.Value/app.AxisMarkerSizeSpinner.Value;
            app.UIAxes.TitleFontSizeMultiplier = app.TitleSizeSpinner.Value/app.AxisMarkerSizeSpinner.Value;
            
            
            if app.FillPointsCheckBox.Value
                plot = scatter(app.UIAxes,app.x_data(app.row_index),app.y_data(app.row_index),app.PointSizeSlider.Value,app.PointColorDropDown.Value,'filled');
            else
                plot = scatter(app.UIAxes,app.x_data(app.row_index),app.y_data(app.row_index),app.PointSizeSlider.Value,app.PointColorDropDown.Value);
            end
            
            
            plot.MarkerFaceAlpha = app.PointTransparencySlider.Value;


            if app.XAxisTypeDropDown.Value == "Log"
                set(app.UIAxes,'xscale','log')
            else 
                set(app.UIAxes,'xscale','linear')
            end
            
            if app.YAxisTypeDropDown.Value == "Log"
                set(app.UIAxes,'yscale','log')
            else 
                set(app.UIAxes,'yscale','linear')
            end
             
             %%% LEGEND
%             linear_plot = scatter(app.UIAxes,app.x_data(app.row_index),app.y_data(app.row_index));            
            
%             legend(app.UIAxes,'TEST')
%             if ~isempty(app.currentPlots)
%                 set(linear_plot,{'DisplayName'},{'IM IN'})
%                 legend(app.UIAxes,'show')
%             else
%                 set(linear_plot,{'DisplayName'},{'TEST'})
%                 legend(app.UIAxes,'show')
%             end


%             % loglog scale
%             app.UIAxes2.Title.String = app.XAxisDropDown.Value + " vs. " + app.YAxisDropDown.Value;
%             app.UIAxes2.XLabel.String = app.XAxisDropDown.Value;
%             app.UIAxes2.YLabel.String = app.YAxisDropDown.Value;
%             
%             scatter(app.UIAxes2,app.x_data(app.row_index),app.y_data(app.row_index))
%             set(app.UIAxes2,'xscale','log')
%             set(app.UIAxes2,'yscale','log')
%             
%             legend(app.UIAxes2, 'show');
            
            % holds plots for groupings when necessary
            if app.holding
                hold(app.UIAxes,'on');
                % hold(app.UIAxes2,'on');
                
                app.CurrentParamsTextArea.Value = ""; % clear gating display
                app.temp_row_index = true([height(app.Data),1]);
                
            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, test)
            
            app.holding = false;
            
        end

        % Value changed function: XAxisDropDown
        function XAxisDropDownValueChanged(app, event)
            
            refreshPlot(app)
            
        end

        % Value changed function: YAxisDropDown
        function YAxisDropDownValueChanged(app, event)
            
            refreshPlot(app)
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            
            % Loads in Data
            [Density.file_fbm,Density.path_fbm] = uigetfile({'*.*','Select the fbm data file.'});
            DensityRead = readtable(strcat(Density.path_fbm,Density.file_fbm));
            colNames = DensityRead.Properties.VariableNames;
                    
            app.Data = DensityRead;            
            
            % loads axis dropdown options based on column names from DensityRead
            [nr,nc] = size(app.Data);
            for i = 2:nc
                app.XAxisDropDown.Items(i-1) = colNames(i); 
                app.YAxisDropDown.Items(i-1) = colNames(i); 
            end            


            % Loads in MetaData
            [Density.file_metadata,Density.path_metadata] = uigetfile({'*.*','Select the metadata file.'});
            DensityRead = readtable(strcat(Density.path_metadata,Density.file_metadata));
            colNames = DensityRead.Properties.VariableNames;
            
            app.metaData = DensityRead;
            app.metaCols = colNames;

            % loads metadata dropdown options based on DensityRead
            [nr,nc] = size(app.metaData);
            app.VariableDropDown.Items(1) = {'None'}; % initialize metadata options
            app.OptionsDropDown.Items(:,:) = [];
            app.OptionsDropDown.Items(1) = {'None'};
            for i = 2:nc
                app.VariableDropDown.Items(i) = app.metaCols(i); 
            end
                                    
            % initializes index properties
            app.row_index = true([height(app.Data),1]);
            app.temp_row_index = true([height(app.Data),1]);
           
            
            refreshPlot(app)
        end

        % Value changed function: VariableDropDown
        function VariableDropDownValueChanged(app, event)
            value = app.VariableDropDown.Value;
            
            % clears out the list
            app.OptionsDropDown.Items(:,:) = [];
            
            % loads in all unique options for gating
            [a,b] = unique(app.metaData(:,value),'stable');
            for i = 1:height(a)
                app.OptionsDropDown.Items(i) = a{i,1};
            end
            
        end

        % Button pushed function: SubmitGatingButton
        function SubmitGatingButtonPushed(app, event)
            
%             % turns gating selecting into string
%             gating = convertCharsToStrings(app.OptionsDropDown.Value);
%             % col is the data column chosen for gating
%             col = app.VariableDropDown.Value;
%                        
%             % finds intersection between existing index and new index. this is set as the new row-index
%             temp_index = app.metaData{:,col} == gating;
%             app.row_index = temp_index & app.row_index;
%              
%             % display current gatings          
%             temp = convertCharsToStrings(app.CurrentParamsTextArea.Value);
%             if temp == ""
%                 app.CurrentParamsTextArea.Value = gating;
%             else
%                 app.CurrentParamsTextArea.Value = temp + ", " + gating;
%             end
            
            app.currentPlots = app.CurrentParamsTextArea;
            disp(class(app.currentPlots)) % matlab.ui.control.TextArea
            disp(class({app.currentPlots}))
            display(class({'TEST1'})) % cell
            
            refreshPlot(app)
            
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)

            app.row_index = true([height(app.Data),1]); % reset row index
            app.temp_row_index = true([height(app.Data),1]);
            
            app.CurrentParamsTextArea.Value = ""; % clear gating display
            
            % clear groupings
            app.holding = false;
            hold(app.UIAxes,'off');
            % hold(app.UIAxes2,'off');
            
            refreshPlot(app)
        end

        % Button pushed function: SubmitGroupingButton
        function SubmitGroupingButtonPushed(app, event)
            
            app.holding = true; 
            
%             % same code as gating except new row_index doesn't stack with previous button press
%             gating = convertCharsToStrings(app.OptionsDropDown.Value);
%             col = app.VariableDropDown.Value;
%             app.row_index = app.metaData{:,col} == gating;
          
            refreshPlot(app)
            
        end

        % Button pushed function: AddParameterButton
        function AddParameterButtonPushed(app, event)
            
            
            % turns gating selecting into string
            gating = convertCharsToStrings(app.OptionsDropDown.Value);
            % col is the data column chosen for gating
            col = app.VariableDropDown.Value;
            
            % finds intersection between existing temp_index and new tmep_index. this is set as the new temp-row-index
            temp_index = app.metaData{:,col} == gating;
            app.temp_row_index = temp_index & app.temp_row_index;
            
            % display current params
            temp = convertCharsToStrings(app.CurrentParamsTextArea.Value);
            if temp == ""
                app.CurrentParamsTextArea.Value = gating;
            else
                app.CurrentParamsTextArea.Value = temp + ", " + gating;
            end            
            
            %refreshPlot(app)
            
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            
            app.CurrentParamsTextArea.Value = ""; % clear gating display
            app.temp_row_index = true([height(app.Data),1]);
            
        end

        % Value changed function: XAxisTypeDropDown
        function XAxisTypeDropDownValueChanged(app, event)
            
            refreshPlot(app)
            
        end

        % Value changed function: YAxisTypeDropDown
        function YAxisTypeDropDownValueChanged(app, event)
            
            refreshPlot(app)
            
        end

        % Value changed function: PointSizeSlider
        function PointSizeSliderValueChanged(app, event)
            
            refreshPlot(app)
            
        end

        % Value changed function: PointColorDropDown
        function PointColorDropDownValueChanged(app, event)
            
            refreshPlot(app)
            
        end

        % Value changed function: FillPointsCheckBox
        function FillPointsCheckBoxValueChanged(app, event)
            
            refreshPlot(app)
            
        end

        % Value changed function: PointTransparencySlider
        function PointTransparencySliderValueChanged(app, event)
                        
            refreshPlot(app)
            
        end

        % Value changed function: AxisMarkerSizeSpinner
        function AxisMarkerSizeSpinnerValueChanged(app, event)

            refreshPlot(app)
            
        end

        % Value changed function: TitleSizeSpinner
        function TitleSizeSpinnerValueChanged(app, event)
            
            refreshPlot(app)
            
        end

        % Value changed function: AxesLabelSizeSpinner
        function AxesLabelSizeSpinnerValueChanged(app, event)
            
            refreshPlot(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AxisSelectionsPanel = uipanel(app.UIFigure);
            app.AxisSelectionsPanel.Title = 'Axis Selections';
            app.XAxisDropDownLabel = uilabel(app.AxisSelectionsPanel);
            app.XAxisDropDownLabel.Text = 'X-Axis';
            app.XAxisDropDown = uidropdown(app.AxisSelectionsPanel);
            app.XAxisDropDown.Items = {'item1', 'item2', 'item3'};
            app.XAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisDropDownValueChanged, true);
            app.XAxisDropDown.Value = 'item1';
            app.YAxisDropDownLabel = uilabel(app.AxisSelectionsPanel);
            app.YAxisDropDownLabel.Text = 'Y-Axis';
            app.YAxisDropDown = uidropdown(app.AxisSelectionsPanel);
            app.YAxisDropDown.Items = {'item1', 'item2', 'item3'};
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDownValueChanged, true);
            app.YAxisDropDown.Value = 'item1';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.UIAxes3 = uiaxes(app.HistogramTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.GatingSelectionsPanel = uipanel(app.UIFigure);
            app.GatingSelectionsPanel.Title = 'Gating Selections';
            app.VariableDropDownLabel = uilabel(app.GatingSelectionsPanel);
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown = uidropdown(app.GatingSelectionsPanel);
            app.VariableDropDown.Items = {'item1'};
            app.VariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableDropDownValueChanged, true);
            app.VariableDropDown.Value = 'item1';
            app.OptionsDropDownLabel = uilabel(app.GatingSelectionsPanel);
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.GatingSelectionsPanel);
            app.OptionsDropDown.Items = {'item1'};
            app.OptionsDropDown.Value = 'item1';
            app.SubmitGatingButton = uibutton(app.GatingSelectionsPanel, 'push');
            app.SubmitGatingButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitGatingButtonPushed, true);
            app.SubmitGatingButton.Text = 'Submit Gating';
            app.CurrentParamsTextAreaLabel = uilabel(app.GatingSelectionsPanel);
            app.CurrentParamsTextAreaLabel.Text = 'Current Params';
            app.CurrentParamsTextArea = uitextarea(app.GatingSelectionsPanel);
            app.SubmitGroupingButton = uibutton(app.GatingSelectionsPanel, 'push');
            app.SubmitGroupingButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitGroupingButtonPushed, true);
            app.SubmitGroupingButton.Text = 'Submit Grouping';
            app.AddParameterButton = uibutton(app.GatingSelectionsPanel, 'push');
            app.AddParameterButton.ButtonPushedFcn = createCallbackFcn(app, @AddParameterButtonPushed, true);
            app.AddParameterButton.Text = 'Add Parameter';
            app.ClearButton = uibutton(app.GatingSelectionsPanel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.SaveFigureButton = uibutton(app.UIFigure, 'push');
            app.SaveFigureButton.Text = 'Save Figure';
            app.GraphOptionsPanel = uipanel(app.UIFigure);
            app.GraphOptionsPanel.Title = 'Graph Options';
            app.XAxisTypeDropDownLabel = uilabel(app.GraphOptionsPanel);
            app.XAxisTypeDropDownLabel.Text = 'X-Axis Type';
            app.XAxisTypeDropDown = uidropdown(app.GraphOptionsPanel);
            app.XAxisTypeDropDown.Items = {'Linear', 'Log'};
            app.XAxisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisTypeDropDownValueChanged, true);
            app.XAxisTypeDropDown.Value = 'Linear';
            app.YAxisTypeDropDownLabel = uilabel(app.GraphOptionsPanel);
            app.YAxisTypeDropDownLabel.Text = 'Y-Axis Type';
            app.YAxisTypeDropDown = uidropdown(app.GraphOptionsPanel);
            app.YAxisTypeDropDown.Items = {'Linear', 'Log'};
            app.YAxisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisTypeDropDownValueChanged, true);
            app.YAxisTypeDropDown.Value = 'Linear';
            app.PointSizeSliderLabel = uilabel(app.GraphOptionsPanel);
            app.PointSizeSliderLabel.Text = 'Point Size';
            app.PointSizeSlider = uislider(app.GraphOptionsPanel);
            app.PointSizeSlider.Limits = [1 100];
            app.PointSizeSlider.MajorTicks = [1 11 21 31 41 51 61 71 81 91 100];
            app.PointSizeSlider.MajorTickLabels = {'1', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.PointSizeSlider.ValueChangedFcn = createCallbackFcn(app, @PointSizeSliderValueChanged, true);
            app.PointSizeSlider.Value = 1;
            app.PointColorDropDownLabel = uilabel(app.GraphOptionsPanel);
            app.PointColorDropDownLabel.Text = 'Point Color';
            app.PointColorDropDown = uidropdown(app.GraphOptionsPanel);
            app.PointColorDropDown.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'white'};
            app.PointColorDropDown.ValueChangedFcn = createCallbackFcn(app, @PointColorDropDownValueChanged, true);
            app.PointColorDropDown.Value = 'red';
            app.PointTransparencySliderLabel = uilabel(app.GraphOptionsPanel);
            app.PointTransparencySliderLabel.Text = 'Point Transparency';
            app.PointTransparencySlider = uislider(app.GraphOptionsPanel);
            app.PointTransparencySlider.Limits = [0 1];
            app.PointTransparencySlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.PointTransparencySlider.ValueChangedFcn = createCallbackFcn(app, @PointTransparencySliderValueChanged, true);
            app.FillPointsCheckBox = uicheckbox(app.GraphOptionsPanel);
            app.FillPointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FillPointsCheckBoxValueChanged, true);
            app.FillPointsCheckBox.Text = 'Fill Points';
            app.AxisMarkerSizeSpinnerLabel = uilabel(app.GraphOptionsPanel);
            app.AxisMarkerSizeSpinnerLabel.Text = 'Axis Marker Size';
            app.AxisMarkerSizeSpinner = uispinner(app.GraphOptionsPanel);
            app.AxisMarkerSizeSpinner.Limits = [1 100];
            app.AxisMarkerSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @AxisMarkerSizeSpinnerValueChanged, true);
            app.AxisMarkerSizeSpinner.Value = 10;
            app.AxesLabelSizeSpinnerLabel = uilabel(app.GraphOptionsPanel);
            app.AxesLabelSizeSpinnerLabel.Text = 'Axes Label Size';
            app.AxesLabelSizeSpinner = uispinner(app.GraphOptionsPanel);
            app.AxesLabelSizeSpinner.Limits = [1 100];
            app.AxesLabelSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @AxesLabelSizeSpinnerValueChanged, true);
            app.AxesLabelSizeSpinner.Value = 10;
            app.TitleSizeSpinnerLabel = uilabel(app.GraphOptionsPanel);
            app.TitleSizeSpinnerLabel.Text = 'Title Size';
            app.TitleSizeSpinner = uispinner(app.GraphOptionsPanel);
            app.TitleSizeSpinner.Limits = [1 100];
            app.TitleSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @TitleSizeSpinnerValueChanged, true);
            app.TitleSizeSpinner.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellDensityApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end